classdef counting_peaks < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        JumpButton                      matlab.ui.control.Button
        NanEditField                    matlab.ui.control.NumericEditField
        NanEditFieldLabel               matlab.ui.control.Label
        Step3getreadytoworkButtonGroup  matlab.ui.container.ButtonGroup
        SaveitButton                    matlab.ui.control.Button
        UndoButton                      matlab.ui.control.Button
        TimeEditField                   matlab.ui.control.NumericEditField
        TimeEditFieldLabel              matlab.ui.control.Label
        IdontwantthisoneButton          matlab.ui.control.Button
        NochangespaceButton             matlab.ui.control.Button
        DownButton                      matlab.ui.control.Button
        UpButton                        matlab.ui.control.Button
        OopsmissingonepeakbeforeafterButton  matlab.ui.control.RadioButton
        ThispeakisButton                matlab.ui.control.RadioButton
        NodataLabel                     matlab.ui.control.Label
        GetreadyButton                  matlab.ui.control.Button
        MinPeakProminenceEditField      matlab.ui.control.NumericEditField
        MinPeakProminenceEditFieldLabel  matlab.ui.control.Label
        Step2setMinPeakProminenceforpeakpickingLabel  matlab.ui.control.Label
        Step1Label                      matlab.ui.control.Label
        ImportetalonsignalmatfileButton  matlab.ui.control.Button
        RightPanel                      matlab.ui.container.Panel
        ZoomSlider                      matlab.ui.control.Slider
        ZoomSliderLabel                 matlab.ui.control.Label
        FocusButton                     matlab.ui.control.StateButton
        OverallButton                   matlab.ui.control.StateButton
        UIAxesrtt                       matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    properties (Access = public)
        matfileLoaded % Description
        wherePeakIs % Description
        xlima
        xlimb
        peaksFoundByMatlab
        etalonRawSignal
        peakCountingTable = [];
        Current_point = 1;
        newPoint
        timeStepAve
        marker
        LastSaveHere % Description
    end

    methods (Access = public)

        function results = take_notes(app,time,up_down_stay)
            app.peakCountingTable = [app.peakCountingTable;[time up_down_stay]];
        end

        function results = focusIt(app,currentpoint)
            if app.Current_point <= numel(app.wherePeakIs)
                app.xlima = app.matfileLoaded(app.wherePeakIs(currentpoint),1)-10*app.ZoomSlider.Value*app.timeStepAve;
                app.xlimb = app.matfileLoaded(app.wherePeakIs(currentpoint),1)+10*app.ZoomSlider.Value*app.timeStepAve;
                xlim(app.UIAxes,[app.xlima app.xlimb])
                xlim(app.UIAxesrtt,[app.xlima app.xlimb])
                app.NanEditField.Value = app.Current_point;
            end
        end

        function results = mark_current_point(app)
            if app.Current_point <= numel(app.wherePeakIs)
                app.marker = plot(app.UIAxes,app.matfileLoaded(app.wherePeakIs(app.Current_point),1),app.matfileLoaded(app.wherePeakIs(app.Current_point),2),'Marker',"square",'Color','b','MarkerSize',30);
            end
        end

        function results = remark_current_point(app)
            delete(app.marker);
        end
        
        function RTT_postprocess(app)

            CountingTable = app.peakCountingTable;
            if numel(CountingTable)/2 > 3
                % Your FSR here (cm^-1)
                FSR = 0.016146;
                % Define Up: default(-1): frequency decrease; custom(1): frequency increase
                f_direction = -1;
                % Generate relative wavenumber table
                for i = 1:height(CountingTable)
                    if i == 1
                        CountingTable(i,2) = 0;
                    else
                        CountingTable(i,2) = CountingTable(i-1,2)+f_direction*CountingTable(i,2)*FSR;
                    end
                end

                plot(app.UIAxesrtt,CountingTable(:,1),CountingTable(:,2),'*')
                if ~isempty(app.LastSaveHere)
                    hold(app.UIAxesrtt,"on");
                    plot(app.UIAxesrtt,[app.LastSaveHere app.LastSaveHere],[max(CountingTable(:,2)),min(CountingTable(:,2))],'r-');
                    hold(app.UIAxesrtt,"off");
                end
                xlim(app.UIAxesrtt,[app.xlima app.xlimb])
                xticklabels(app.UIAxesrtt,{})
                yticklabels(app.UIAxesrtt,{})
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             xticklabels(app.UIAxesrtt,{})
             yticklabels(app.UIAxesrtt,{})
        end

        % Button pushed function: ImportetalonsignalmatfileButton
        function ImportetalonsignalmatfileButtonPushed(app, event)
            [file,pat] = uigetfile('*.*');
            if file ~= 0
                uialert(app.UIFigure,'Imported successfully！','Info','Icon',"success")
                app.NodataLabel.Text = ['load@',file];
                app.matfileLoaded = load([pat,'/',file]);
                app.matfileLoaded = app.matfileLoaded.saves;
            else
                uialert(app.UIFigure,'Operation Abort','Info','Icon',"error")
            end
        end

        % Button pushed function: GetreadyButton
        function GetreadyButtonPushed(app, event)
            hold(app.UIAxes,"off")


            app.etalonRawSignal = plot(app.UIAxes,app.matfileLoaded(:,1),app.matfileLoaded(:,2),'LineStyle','-','LineWidth',1.5,'Color',[0.5 0.5 0.5]);
            hold(app.UIAxes,"on")
            % find peaks
            [~,locs] = findpeaks(app.matfileLoaded(:,2),'MinPeakProminence',app.MinPeakProminenceEditField.Value);
            app.peaksFoundByMatlab = plot(app.UIAxes,app.matfileLoaded(locs,1),app.matfileLoaded(locs,2),'Marker',"o",'MarkerSize',10,'Color','b','LineStyle',"none");

            app.wherePeakIs = locs;

            time_locs = app.matfileLoaded(locs,1);
            app.timeStepAve = sum(diff(time_locs))/(numel(time_locs)-1);
            app.focusIt(app.Current_point);
            app.NanEditFieldLabel.Text = ['(1 - ',num2str(numel(locs)),')'];
            app.NanEditField.Limits = [1 numel(locs)];
            app.ZoomSlider.Enable = 'on';
            app.JumpButton.Enable = 'on';
            app.SaveitButton.Enable = 'on';
            app.mark_current_point();
        end

        % Value changed function: FocusButton
        function FocusButtonValueChanged(app, event)
            value = app.FocusButton.Value;
            if app.NodataLabel.Text == "No data"
                app.FocusButton.Value = ~value;
                return
            end
            if value == 1
                app.focusIt(app.Current_point);
            end
            app.OverallButton.Value = 0;
        end

        % Value changed function: OverallButton
        function OverallButtonValueChanged(app, event)
            value = app.OverallButton.Value;
            if app.NodataLabel.Text == "No data"
                app.OverallButton.Value = ~value;
                return
            end
            if value == 1
                xlim(app.UIAxes,[-inf,inf]);
                xlim(app.UIAxesrtt,[-inf,inf]);
            end
            app.FocusButton.Value = 0;
        end

        % Button pushed function: UpButton
        function UpButtonPushed(app, event)
            if app.Current_point<=numel(app.wherePeakIs)
                if app.ThispeakisButton.Value == 1                    
                    if numel(app.peakCountingTable) > 0 & app.peakCountingTable(end,2) == -1
                        selection = uiconfirm(app.UIFigure,"Do you want to press 'No change' ?",'Confirm','Icon','warning','Options',{'Yes','No'});
                        if selection == "Yes"
                            app.newPoint = plot(app.UIAxes,app.matfileLoaded(app.wherePeakIs(app.Current_point),1),app.matfileLoaded(app.wherePeakIs(app.Current_point),2),'Marker',"square",'Color','c','MarkerSize',8,'MarkerFaceColor','k');
                   
                            app.take_notes(app.matfileLoaded(app.wherePeakIs(app.Current_point),1),0)
                        else
                            app.newPoint = plot(app.UIAxes,app.matfileLoaded(app.wherePeakIs(app.Current_point),1),app.matfileLoaded(app.wherePeakIs(app.Current_point),2),'Marker',"^",'Color','k','MarkerSize',8,'MarkerFaceColor','k');
                   
                            app.take_notes(app.matfileLoaded(app.wherePeakIs(app.Current_point),1),+1)
                        end
                    else
                        app.newPoint = plot(app.UIAxes,app.matfileLoaded(app.wherePeakIs(app.Current_point),1),app.matfileLoaded(app.wherePeakIs(app.Current_point),2),'Marker',"^",'Color','k','MarkerSize',8,'MarkerFaceColor','k');
                        app.take_notes(app.matfileLoaded(app.wherePeakIs(app.Current_point),1),+1)
                    end       
                    app.Current_point = app.Current_point + 1;
                elseif app.OopsmissingonepeakbeforeafterButton.Value == 1
                    app.newPoint = plot(app.UIAxes,app.TimeEditField.Value,app.matfileLoaded(app.wherePeakIs(app.Current_point),2),'Marker',"^",'Color','r','MarkerSize',8,'MarkerFaceColor','k');
                    app.take_notes(app.TimeEditField.Value,+1)
                end
                if app.Current_point<=numel(app.wherePeakIs)
                    app.focusIt(app.Current_point);
                end
                xlim(app.UIAxes,[app.xlima,app.xlimb]);
                app.UndoButton.Enable = 'on';
                app.remark_current_point();
                app.mark_current_point();
                app.RTT_postprocess();
            end
        end

        % Button pushed function: DownButton
        function DownButtonPushed(app, event)
            if app.Current_point<=numel(app.wherePeakIs)
                if app.ThispeakisButton.Value == 1
                    if numel(app.peakCountingTable) > 0 & app.peakCountingTable(end,2) == 1
                        selection = uiconfirm(app.UIFigure,"Do you want to press 'No change' ?",'Confirm','Icon','warning','Options',{'Yes','No'});
                        if selection == "Yes"
                            app.newPoint = plot(app.UIAxes,app.matfileLoaded(app.wherePeakIs(app.Current_point),1),app.matfileLoaded(app.wherePeakIs(app.Current_point),2),'Marker',"square",'Color','c','MarkerSize',8,'MarkerFaceColor','k');
                   
                            app.take_notes(app.matfileLoaded(app.wherePeakIs(app.Current_point),1),0)
                        else
                            app.newPoint = plot(app.UIAxes,app.matfileLoaded(app.wherePeakIs(app.Current_point),1),app.matfileLoaded(app.wherePeakIs(app.Current_point),2),'Marker',"v",'Color','k','MarkerSize',8,'MarkerFaceColor','k');
                   
                            app.take_notes(app.matfileLoaded(app.wherePeakIs(app.Current_point),1),-1)
                        end
                    else
                        app.newPoint = plot(app.UIAxes,app.matfileLoaded(app.wherePeakIs(app.Current_point),1),app.matfileLoaded(app.wherePeakIs(app.Current_point),2),'Marker',"v",'Color','k','MarkerSize',8,'MarkerFaceColor','k');
                        app.take_notes(app.matfileLoaded(app.wherePeakIs(app.Current_point),1),-1)
                    end
                    app.Current_point = app.Current_point + 1;
                elseif app.OopsmissingonepeakbeforeafterButton.Value == 1
                    app.newPoint = plot(app.UIAxes,app.TimeEditField.Value,app.matfileLoaded(app.wherePeakIs(app.Current_point),2),'Marker',"v",'Color','r','MarkerSize',8,'MarkerFaceColor','k');
                    app.take_notes(app.TimeEditField.Value,-1)
                end

                if app.Current_point<=numel(app.wherePeakIs)
                    app.focusIt(app.Current_point);
                end
                xlim(app.UIAxes,[app.xlima,app.xlimb]);
                app.UndoButton.Enable = 'on';
                app.remark_current_point();
                app.mark_current_point();
                app.RTT_postprocess();
            end
        end

        % Button pushed function: NochangespaceButton
        function NochangespaceButtonPushed(app, event)
            if app.Current_point<=numel(app.wherePeakIs)
                if app.ThispeakisButton.Value == 1
                    app.newPoint = plot(app.UIAxes,app.matfileLoaded(app.wherePeakIs(app.Current_point),1),app.matfileLoaded(app.wherePeakIs(app.Current_point),2),'Marker',"square",'Color','c','MarkerSize',8,'MarkerFaceColor','k');
                    app.take_notes(app.matfileLoaded(app.wherePeakIs(app.Current_point),1),0)
                    app.Current_point = app.Current_point + 1;
                elseif app.OopsmissingonepeakbeforeafterButton.Value == 1
                    app.newPoint = plot(app.UIAxes,app.TimeEditField.Value,app.matfileLoaded(app.wherePeakIs(app.Current_point),2),'Marker',"square",'Color','r','MarkerSize',8,'MarkerFaceColor','k');
                    app.take_notes(app.TimeEditField.Value,0)
                end

                if app.Current_point<=numel(app.wherePeakIs)
                    app.focusIt(app.Current_point);
                end
                xlim(app.UIAxes,[app.xlima,app.xlimb]);
                app.UndoButton.Enable = 'on';
                app.remark_current_point();
                app.mark_current_point();
            end
            app.RTT_postprocess();
        end

        % Button pushed function: IdontwantthisoneButton
        function IdontwantthisoneButtonPushed(app, event)
            if app.Current_point<=numel(app.wherePeakIs)
                app.Current_point = app.Current_point + 1;
                if app.Current_point<=numel(app.wherePeakIs)
                    app.focusIt(app.Current_point);
                end
                xlim(app.UIAxes,[app.xlima,app.xlimb]);
                app.UndoButton.Enable = 'on';
                app.remark_current_point();
                app.mark_current_point();
            end
            app.RTT_postprocess();
        end

        % Button pushed function: UndoButton
        function UndoButtonPushed(app, event)
            if app.Current_point > 1 & app.Current_point<=numel(app.wherePeakIs)
                delete(app.newPoint)
                if app.ThispeakisButton.Value == 1
                    app.Current_point = app.Current_point - 1;
                    app.focusIt(app.Current_point);
                end
                app.peakCountingTable = app.peakCountingTable(1:end-1,:);
                app.UndoButton.Enable = 'off';
                app.remark_current_point();
                app.mark_current_point();
            end
            app.RTT_postprocess();
        end

        % Button pushed function: SaveitButton
        function SaveitButtonPushed(app, event)
            CountingTable = app.peakCountingTable;
            peaklocs = app.wherePeakIs;
            currentpoint = app.Current_point;
            save('results','CountingTable','peaklocs','currentpoint');
            uialert(app.UIFigure,'File saved to your working directory named results.mat','Info','Icon',"success")
            app.LastSaveHere = app.peakCountingTable(end,1);
            app.RTT_postprocess();
        end

        % Value changed function: ZoomSlider
        function ZoomSliderValueChanged(app, event)
            app.focusIt(app.Current_point);

        end

        % Button pushed function: JumpButton
        function JumpButtonPushed(app, event)
            if app.NodataLabel.Text ~= "No data"
                app.Current_point = app.NanEditField.Value;
                app.focusIt(app.Current_point);
                app.remark_current_point();
                app.mark_current_point();
            end
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            if app.NodataLabel.Text ~= "No data"
                if key == "uparrow"
                    app.UpButtonPushed()
                end

                if key == "downarrow"
                    app.DownButtonPushed()
                end

                if key == "rightarrow"
                    app.IdontwantthisoneButtonPushed()
                end

                if key == "space"
                    app.NochangespaceButtonPushed()
                end
                
                if key == "leftarrow"
                    app.NochangespaceButtonPushed()
                end
                
                if (string(event.Modifier) == "command" | string(event.Modifier) == "control" )& key == "z" & app.UndoButton.Enable == 'on'
                    app.UndoButtonPushed()
                end
            end

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {413, 413};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {295, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Counting Peaks';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ImportetalonsignalmatfileButton = uibutton(app.LeftPanel, 'push');
            app.ImportetalonsignalmatfileButton.ButtonPushedFcn = createCallbackFcn(app, @ImportetalonsignalmatfileButtonPushed, true);
            app.ImportetalonsignalmatfileButton.Text = 'Import etalon signal (mat file)';
            app.Step1Label = uilabel(app.LeftPanel);
            app.Step1Label.Text = 'Step 1';
            app.Step2setMinPeakProminenceforpeakpickingLabel = uilabel(app.LeftPanel);
            app.Step2setMinPeakProminenceforpeakpickingLabel.Text = 'Step 2: set MinPeakProminence for peak picking';
            app.MinPeakProminenceEditFieldLabel = uilabel(app.LeftPanel);
            app.MinPeakProminenceEditFieldLabel.Text = 'MinPeakProminence';
            app.MinPeakProminenceEditField = uieditfield(app.LeftPanel, 'numeric');
            app.MinPeakProminenceEditField.Value = 0.1;
            app.GetreadyButton = uibutton(app.LeftPanel, 'push');
            app.GetreadyButton.ButtonPushedFcn = createCallbackFcn(app, @GetreadyButtonPushed, true);
            app.GetreadyButton.Text = 'Get ready !';
            app.NodataLabel = uilabel(app.LeftPanel);
            app.NodataLabel.Text = 'No data';
            app.Step3getreadytoworkButtonGroup = uibuttongroup(app.LeftPanel);
            app.Step3getreadytoworkButtonGroup.Title = 'Step 3: get ready to work';
            app.ThispeakisButton = uiradiobutton(app.Step3getreadytoworkButtonGroup);
            app.ThispeakisButton.Text = 'This peak is ';
            app.ThispeakisButton.Value = true;
            app.OopsmissingonepeakbeforeafterButton = uiradiobutton(app.Step3getreadytoworkButtonGroup);
            app.OopsmissingonepeakbeforeafterButton.Text = 'Oops ! missing one peak before/after';
            app.UpButton = uibutton(app.Step3getreadytoworkButtonGroup, 'push');
            app.UpButton.ButtonPushedFcn = createCallbackFcn(app, @UpButtonPushed, true);
            app.UpButton.Text = 'Up (↑)';
            app.DownButton = uibutton(app.Step3getreadytoworkButtonGroup, 'push');
            app.DownButton.ButtonPushedFcn = createCallbackFcn(app, @DownButtonPushed, true);
            app.DownButton.Text = 'Down(↓)';
            app.NochangespaceButton = uibutton(app.Step3getreadytoworkButtonGroup, 'push');
            app.NochangespaceButton.ButtonPushedFcn = createCallbackFcn(app, @NochangespaceButtonPushed, true);
            app.NochangespaceButton.Text = {'No change '; '(space)'};
            app.IdontwantthisoneButton = uibutton(app.Step3getreadytoworkButtonGroup, 'push');
            app.IdontwantthisoneButton.ButtonPushedFcn = createCallbackFcn(app, @IdontwantthisoneButtonPushed, true);
            app.IdontwantthisoneButton.Text = 'I don''t want this one(→)';
            app.TimeEditFieldLabel = uilabel(app.Step3getreadytoworkButtonGroup);
            app.TimeEditFieldLabel.Text = 'Time';
            app.TimeEditField = uieditfield(app.Step3getreadytoworkButtonGroup, 'numeric');
            app.TimeEditField.ValueDisplayFormat = '%11.8g';
            app.UndoButton = uibutton(app.Step3getreadytoworkButtonGroup, 'push');
            app.UndoButton.ButtonPushedFcn = createCallbackFcn(app, @UndoButtonPushed, true);
            app.UndoButton.Text = 'Undo';
            app.SaveitButton = uibutton(app.Step3getreadytoworkButtonGroup, 'push');
            app.SaveitButton.ButtonPushedFcn = createCallbackFcn(app, @SaveitButtonPushed, true);
            app.SaveitButton.Enable = 'off';
            app.SaveitButton.Text = 'Save it !';
            app.NanEditFieldLabel = uilabel(app.LeftPanel);
            app.NanEditFieldLabel.Text = '(1 - Nan)';
            app.NanEditField = uieditfield(app.LeftPanel, 'numeric');
            app.NanEditField.Limits = [1 Inf];
            app.NanEditField.RoundFractionalValues = 'on';
            app.NanEditField.Value = 1;
            app.JumpButton = uibutton(app.LeftPanel, 'push');
            app.JumpButton.ButtonPushedFcn = createCallbackFcn(app, @JumpButtonPushed, true);
            app.JumpButton.Enable = 'off';
            app.JumpButton.Text = 'Jump !';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Your nightmare')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Signal amplitude (V)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxesrtt = uiaxes(app.RightPanel);
            title(app.UIAxesrtt, 'Real-time tracking')
            zlabel(app.UIAxesrtt, 'Z')
            app.OverallButton = uibutton(app.RightPanel, 'state');
            app.OverallButton.ValueChangedFcn = createCallbackFcn(app, @OverallButtonValueChanged, true);
            app.OverallButton.Text = 'Overall';
            app.FocusButton = uibutton(app.RightPanel, 'state');
            app.FocusButton.ValueChangedFcn = createCallbackFcn(app, @FocusButtonValueChanged, true);
            app.FocusButton.Text = 'Focus';
            app.FocusButton.Value = true;
            app.ZoomSliderLabel = uilabel(app.RightPanel);
            app.ZoomSliderLabel.Text = 'Zoom';
            app.ZoomSlider = uislider(app.RightPanel);
            app.ZoomSlider.Limits = [0.1 20];
            app.ZoomSlider.MajorTicks = [];
            app.ZoomSlider.MajorTickLabels = {''};
            app.ZoomSlider.ValueChangedFcn = createCallbackFcn(app, @ZoomSliderValueChanged, true);
            app.ZoomSlider.MinorTicks = [];
            app.ZoomSlider.Enable = 'off';
            app.ZoomSlider.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = counting_peaks

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end